classdef MPA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Menu                        matlab.ui.container.Menu
        Menu_2                      matlab.ui.container.Menu
        ProfilePanel                matlab.ui.container.Panel
        SelectProfileDropDownLabel  matlab.ui.control.Label
        SelectProfileDropDown       matlab.ui.control.DropDown
        ProfileTable                matlab.ui.control.Table
        NewProfileButton            matlab.ui.control.Button
        ReportPanel                 matlab.ui.container.Panel
        AnalyzedDataLabel           matlab.ui.control.Label
        AnalyzedData                matlab.ui.control.ListBox
        LoadCheckBox                matlab.ui.control.CheckBox
        PerformanceCheckBox         matlab.ui.control.CheckBox
        IndividualCheckBox          matlab.ui.control.CheckBox
        TeamCheckBox                matlab.ui.control.CheckBox
        ReferenceCheckBox           matlab.ui.control.CheckBox
        CreateReportButton          matlab.ui.control.Button
        ExportButton                matlab.ui.control.Button
        AnalysisPanel               matlab.ui.container.Panel
        AvailableDataLabel          matlab.ui.control.Label
        AvailableData               matlab.ui.control.ListBox
        SelectDataButton            matlab.ui.control.Button
        LampLabel                   matlab.ui.control.Label
        Lamp                        matlab.ui.control.Lamp
        SearchbyDataFolderButton    matlab.ui.control.Button
        AnalyzeDataButton           matlab.ui.control.Button
        ReferencePanel              matlab.ui.container.Panel
        SessionReferenceLabel       matlab.ui.control.Label
        SessionReference            matlab.ui.control.ListBox
        IndividualReferenceTable    matlab.ui.control.Table
        IndividualReferenceLabel    matlab.ui.control.Label
        ReferenceManagerButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        DataProfile; DataSource; DataBase
        VarNames; Mail; AllRef
        ProfileLabels; DirNames 
        ProfileId; RootF; DataF
        varset;GameId; SourceId; RefId; PInd; ts; tsg;
    end
    
    properties (Access = public)
        SystemPath=['C:\Users\' getenv('username') '\MATLAB Drive\MP']
    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath (genpath(app.SystemPath))
            addpath (genpath('C:\ProgramFiles\MATLAB\R2019b'))
            cd(app.SystemPath)
            load('Settings.mat')
            load('DataBase.mat')
            app.DataProfile=DataProfile;
            app.AllRef=AllRef;
            app.DataSource=DataSource;
            app.Mail=Mail;
            app.VarNames=VarNames;
            app.DataBase=DataBase;
            app.ProfileLabels=[app.SelectProfileDropDown.Items app.DataProfile.Name{:}];
            app.SelectProfileDropDown.Items = app.ProfileLabels;
           
            
            %%get initial states from app and add to profile callback
            %%'none'
            
        end

        % Value changed function: SelectProfileDropDown
        function SelectProfileDropDownValueChanged(app, event)
            value = app.SelectProfileDropDown.Value;
            app.ProfileId= find(strcmp(value,app.ProfileLabels),1)-1
            
            if app.ProfileId==0 % check for 'none' and set state
                % don't show data
                app.ProfileTable.Data=[];
                app.ProfileTable.ColumnName={};
                app.AnalyzedData.Items={};
                app.AvailableData.Items = {};
                app.SessionReference.Items={};                             
                app.IndividualReferenceTable.Data=[];
                app.IndividualReferenceTable.ColumnName={};
                
%                 % disable windows
%                 app.ProfileTable.Enable = 'off';
%                 app.AnalyzedData.Enable = 'off';
%                 app.AvailableDataLabel.Enable = 'off';
%                 app.SessionReference.Enable = 'off';
%                 app.IndividualReferenceTable.Enable = 'off';
            else
            
            % update analyzed data
            if isempty(app.DataBase{1,app.ProfileId})
               app.AnalyzedData.Items={'no data'};
%                app.AnalyzedData.Enable = 'off';     
            else
%                app.AnalyzedData.Enable = 'on';
               NewItems={app.DataBase{1,app.ProfileId}.Einheit};
               app.AnalyzedData.Items=NewItems;
            end

            % update individual reference
            if isempty(app.AllRef{1,app.ProfileId}{1,1})
               app.IndividualReferenceTable.Data=[];
               app.IndividualReferenceTable.ColumnName={};
            else                
                NewIndividualReference=app.AllRef{1,app.ProfileId}{1,1};
                app.IndividualReferenceTable.Data=NewIndividualReference;
                app.IndividualReferenceTable.ColumnName = NewIndividualReference.Properties.VariableNames;
            end
            
            %update profile table
                NewProfileTable=app.DataProfile(app.ProfileId,:);
                app.ProfileTable.Data=NewProfileTable;
                app.ProfileTable.ColumnName = app.DataProfile.Properties.VariableNames;
            % update Profile
                [app.RootF,app.DataF,app.varset,app.GameId,...
                    app.SourceId,app.RefId,app.PInd,app.ts,app.tsg]=profileset(app.DataProfile,app.DataSource,app.ProfileId);
            % update Ref
            app.TeamRef=app.AllRef{app.RefId}; % dataref % function for ind. and team references - calculate or select
                app.Norm=TeamRef{1}; app.Ref=TeamRef{2}; app.Field=TeamRef{3};
            end  
        end

        % Button pushed function: SelectDataButton
        function SelectDataButtonPushed(app, event)
            try
            app.DirNames=uigetdir2;
            app.AvailableData.Items=app.DirNames;
            catch
            end
            
        end

        % Button pushed function: SearchbyDataFolderButton
        function SearchbyDataFolderButtonPushed(app, event)
            cd(app.DataF)
            % X=dir('*.xls'); X={X.name};              % X <- Übersichtsdateien
            Y=dir('2*'); Y=Y([Y.isdir]); Y={Y.name}; % Y <- Ordner der Spiele 
            app.AvailableData.Items=Y;
        end

        % Button pushed function: CreateReportButton
        function CreateReportButtonPushed(app, event)
            ProfileId=app.ProfileId        % 1=VFL 2=Hockey
            [RootF,DataF,varset,GameId,SourceId,RefId,PInd,ts,tsg]=...
                profileset(app.DataProfile,app.DataSource,app.ProfileId);
       
            TeamRef=app.AllRef{app.RefId}; % dataref % function for ind. and team references - calculate or select
                Norm=TeamRef{1}; Ref=TeamRef{2}; Field=TeamRef{3};

            % X=
            % Y=  
            % run MP2020.m

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.Text = 'Menu';
            app.ProfilePanel = uipanel(app.UIFigure);
            app.SelectProfileDropDownLabel = uilabel(app.ProfilePanel);
            app.SelectProfileDropDownLabel.Text = 'Select Profile';
            app.SelectProfileDropDown = uidropdown(app.ProfilePanel);
            app.SelectProfileDropDown.Items = {'None'};
            app.SelectProfileDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectProfileDropDownValueChanged, true);
            app.SelectProfileDropDown.Value = 'None';
            app.ProfileTable = uitable(app.ProfilePanel);
            app.ProfileTable.ColumnName = {''};
            app.ProfileTable.RowName = {};
            app.NewProfileButton = uibutton(app.ProfilePanel, 'push');
            app.NewProfileButton.Text = 'New Profile';
            app.ReportPanel = uipanel(app.UIFigure);
            app.AnalyzedDataLabel = uilabel(app.ReportPanel);
            app.AnalyzedDataLabel.Text = 'Analyzed Data';
            app.AnalyzedData = uilistbox(app.ReportPanel);
            app.AnalyzedData.Items = {};
            app.AnalyzedData.Multiselect = 'on';
            app.AnalyzedData.Value = {};
            app.LoadCheckBox = uicheckbox(app.ReportPanel);
            app.LoadCheckBox.Text = 'Load';
            app.PerformanceCheckBox = uicheckbox(app.ReportPanel);
            app.PerformanceCheckBox.Text = 'Performance';
            app.IndividualCheckBox = uicheckbox(app.ReportPanel);
            app.IndividualCheckBox.Text = 'Individual';
            app.TeamCheckBox = uicheckbox(app.ReportPanel);
            app.TeamCheckBox.Text = 'Team';
            app.ReferenceCheckBox = uicheckbox(app.ReportPanel);
            app.ReferenceCheckBox.Text = 'Reference';
            app.CreateReportButton = uibutton(app.ReportPanel, 'push');
            app.CreateReportButton.ButtonPushedFcn = createCallbackFcn(app, @CreateReportButtonPushed, true);
            app.CreateReportButton.Text = 'Create Report';
            app.ExportButton = uibutton(app.ReportPanel, 'push');
            app.ExportButton.Text = 'Export';
            app.AnalysisPanel = uipanel(app.UIFigure);
            app.AvailableDataLabel = uilabel(app.AnalysisPanel);
            app.AvailableDataLabel.Text = 'Available Data';
            app.AvailableData = uilistbox(app.AnalysisPanel);
            app.AvailableData.Items = {};
            app.AvailableData.Multiselect = 'on';
            app.AvailableData.Value = {};
            app.SelectDataButton = uibutton(app.AnalysisPanel, 'push');
            app.SelectDataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataButtonPushed, true);
            app.SelectDataButton.Text = 'Select Data';
            app.LampLabel = uilabel(app.AnalysisPanel);
            app.LampLabel.Text = '...';
            app.Lamp = uilamp(app.AnalysisPanel);
            app.SearchbyDataFolderButton = uibutton(app.AnalysisPanel, 'push');
            app.SearchbyDataFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SearchbyDataFolderButtonPushed, true);
            app.SearchbyDataFolderButton.Text = {'Search by'; 'Data Folder'};
            app.AnalyzeDataButton = uibutton(app.AnalysisPanel, 'push');
            app.AnalyzeDataButton.Text = 'Analyze Data';
            app.ReferencePanel = uipanel(app.UIFigure);
            app.SessionReferenceLabel = uilabel(app.ReferencePanel);
            app.SessionReferenceLabel.Text = 'Session Reference';
            app.SessionReference = uilistbox(app.ReferencePanel);
            app.SessionReference.Items = {};
            app.SessionReference.Multiselect = 'on';
            app.SessionReference.Value = {};
            app.IndividualReferenceTable = uitable(app.ReferencePanel);
            app.IndividualReferenceTable.ColumnName = {'LfdNr'; 'SpielerNr'; 'Name'; 'Vorname'; 'Alter'; 'Größe'; 'Gewicht'; 'MP4'; 'EC0'};
            app.IndividualReferenceTable.RowName = {};
            app.IndividualReferenceLabel = uilabel(app.ReferencePanel);
            app.IndividualReferenceLabel.Text = 'Individual Reference';
            app.ReferenceManagerButton = uibutton(app.ReferencePanel, 'push');
            app.ReferenceManagerButton.Text = 'Reference Manager';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MPA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end