classdef APIapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        AnalysisPanel               matlab.ui.container.Panel
        SelectDataforDownloadLabel  matlab.ui.control.Label
        OnlineData                  matlab.ui.control.ListBox
        Lamp                        matlab.ui.control.Lamp
        RefreshButton               matlab.ui.control.Button
        DownloadButton              matlab.ui.control.Button
        RemovedownloadedButton      matlab.ui.control.Button
        FilterTRAININGButton        matlab.ui.control.Button
        FilterGAMEButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        MPA % Calling app
        AllSessions % input from calling app
        Times % starting time from AllSessions to compare to selected values
        M % methods for downloading data
        P % Profile data
    end
    
    methods (Access = private)
        
        function Times=updatePanel(app,AllSessions)
            Times={AllSessions.start_time};
            Times=datetime(Times,'InputFormat', 'uuuu-MM-dd''T''HH:mm:ss');
            Times=string(datestr(Times,'yyyymmdd_HHMMSS'));
            app.OnlineData.Items=Times;            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MPA, AllSessions, M, P)
             % store main app object
             
            app.MPA = MPA;
            app.AllSessions=AllSessions;
            app.M=M;
            app.P=P;
            
            % update panel
            
            app.Times=updatePanel(app, app.AllSessions);
            
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            updatePanel(app, app.AllSessions)
        end

        % Button pushed function: FilterTRAININGButton
        function FilterTRAININGButtonPushed(app, event)
            
            % update panel
            
            updatePanel(app, app.AllSessions)
            
            % find indices
            
            id=ismember({app.AllSessions.type},'TRAINING');
            NewData=app.OnlineData.Items(id);
            
            % update panel
            
            app.OnlineData.Items=NewData;
            
        end

        % Button pushed function: FilterGAMEButton
        function FilterGAMEButtonPushed(app, event)
            
            % update panel
            
            updatePanel(app, app.AllSessions)
            
            % find indices
            
            id=ismember({app.AllSessions.type},'GAME');
            NewData=app.OnlineData.Items(id);
            
            % update panel
            
            app.OnlineData.Items=NewData;
            
        end

        % Button pushed function: RemovedownloadedButton
        function RemovedownloadedButtonPushed(app, event)
             % get items from panels
            
            Av=string(app.MPA.AvailableData.Items);
            On=string(app.OnlineData.Items);
            
            % transform to match yyyymmdd_HHMMSS

            Av=extractBefore(Av,".mat");      
            
            % delete analyzed items
            
            id=ismember(On,Av);
            sum(id);
            app.OnlineData.Items(id)=[];
        end

        % Button pushed function: DownloadButton
        function DownloadButtonPushed(app, event)
             % Lamp green
          
            app.Lamp.Color='r';
          
            % find index
            
            id=ismember(app.Times,app.OnlineData.Value);
            DlSessions=app.AllSessions(id);
            
            % download data
            
            dlSessionData(DlSessions,app.M, app.P.Datafolder);
            
            % update available data panel
            
            % Call main app's public function
            
            updateAvailableData(app.MPA)

            % Lamp red
          
            app.Lamp.Color='g';
            
            % update panel in mainapp
            
            updateAvailableData(app.MPA)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AnalysisPanel = uipanel(app.UIFigure);
            app.SelectDataforDownloadLabel = uilabel(app.AnalysisPanel);
            app.SelectDataforDownloadLabel.Text = 'Select Data for Download';
            app.OnlineData = uilistbox(app.AnalysisPanel);
            app.OnlineData.Items = {};
            app.OnlineData.Multiselect = 'on';
            app.OnlineData.Value = {};
            app.Lamp = uilamp(app.AnalysisPanel);
            app.RefreshButton = uibutton(app.AnalysisPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.DownloadButton = uibutton(app.AnalysisPanel, 'push');
            app.DownloadButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadButtonPushed, true);
            app.DownloadButton.Text = 'Download';
            app.RemovedownloadedButton = uibutton(app.AnalysisPanel, 'push');
            app.RemovedownloadedButton.ButtonPushedFcn = createCallbackFcn(app, @RemovedownloadedButtonPushed, true);
            app.RemovedownloadedButton.Text = 'Remove downloaded';
            app.FilterTRAININGButton = uibutton(app.AnalysisPanel, 'push');
            app.FilterTRAININGButton.ButtonPushedFcn = createCallbackFcn(app, @FilterTRAININGButtonPushed, true);
            app.FilterTRAININGButton.Tooltip = {'refreshes before filtering'};
            app.FilterTRAININGButton.Text = 'Filter TRAINING';
            app.FilterGAMEButton = uibutton(app.AnalysisPanel, 'push');
            app.FilterGAMEButton.ButtonPushedFcn = createCallbackFcn(app, @FilterGAMEButtonPushed, true);
            app.FilterGAMEButton.Tooltip = {'refreshes before filtering'};
            app.FilterGAMEButton.Text = 'Filter GAME';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APIapp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end