classdef MPA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ProfilePanel                matlab.ui.container.Panel
        SelectProfileDropDownLabel  matlab.ui.control.Label
        SelectProfileDropDown       matlab.ui.control.DropDown
        ProfileTable                matlab.ui.control.Table
        NewProfileButton            matlab.ui.control.Button
        DeleteProfileButton         matlab.ui.control.Button
        SaveProfileButton           matlab.ui.control.Button
        ReportPanel                 matlab.ui.container.Panel
        AnalyzedDataLabel           matlab.ui.control.Label
        AnalyzedData                matlab.ui.control.ListBox
        CreateReportButton          matlab.ui.control.Button
        ExportButton                matlab.ui.control.Button
        CreateReferenceButton       matlab.ui.control.Button
        Switch                      matlab.ui.control.Switch
        AnalysisPanel               matlab.ui.container.Panel
        AvailableDataLabel          matlab.ui.control.Label
        AvailableData               matlab.ui.control.ListBox
        Lamp                        matlab.ui.control.Lamp
        AnalyzeDataButton           matlab.ui.control.Button
        RefreshButton               matlab.ui.control.Button
        RemoveAnalyzedButton        matlab.ui.control.Button
        OnlineDataButton            matlab.ui.control.Button
        ReferencePanel              matlab.ui.container.Panel
        SessionReferenceLabel       matlab.ui.control.Label
        SessionReference            matlab.ui.control.ListBox
        IndividualReferenceTable    matlab.ui.control.Table
        IndividualReferenceLabel    matlab.ui.control.Label
        DeleteReferenceButton       matlab.ui.control.Button
        ExportTableButton           matlab.ui.control.Button
        ImportTableButton           matlab.ui.control.Button
    end


    properties (Access = private)
        S % Settings: Profiles, VarNames, Source, Fields
        P % Profile: for Profile (ID) contains Rootfolder,Datafolder,SourceId, etc.
        Files
        ProfileId
        ProfileLabels
        DirNames
        VarNames
        baseF
        APIapp % API app
        ProfileApp % Create new profile
    end

    properties (Access = public)
        SystemPath
        Datum
    end

    methods (Access = private)
        
        function able(app,str)

            app.ReferencePanel.Enable=str;
            app.AnalysisPanel.Enable=str;
            app.ReportPanel.Enable=str;
            app.DeleteProfileButton.Enable=str;
            app.SaveProfileButton.Enable=str;
            
            if str=="on" && app.P.SourceId==3
                app.OnlineDataButton.Enable=str;
            else
                app.OnlineDataButton.Enable="off";
            end
            
            
        end
        
        function Files = pickDataToAnalyze(app)
            
            NewList=app.AvailableData.Value;
            [~,id]=ismember(NewList,app.Files.Y);
            Files.Y=app.Files.Y(id);
            if app.P.SourceId==1
                Files.X=app.Files.X(id);
            end
            
        end
        
        function Files = pickAnalyzedData(app)
            NewList=app.AnalyzedData.Value;
            [~,id]=ismember(NewList,app.Files.Y);
            Files.Y=app.Files.Y(id);
            if app.P.SourceId==1
            Files.X=app.Files.X(id);
            end
        end
        
        function app = updateRefPanel (app)
            RefList={};
            try
                RefList={app.P.Ref.Name};
            catch
            end
            app.SessionReference.Items=RefList;
        end
        
        function saveSettings(app)
            cd(app.baseF)
            checkSource(app)
            S=app.S;
            save Settings.mat S
        end
        
        function updateIndReference(app)
            NewIndividualReference=app.P.Norm;
            app.IndividualReferenceTable.Data=NewIndividualReference;
            app.IndividualReferenceTable.ColumnName = NewIndividualReference.Properties.VariableNames;
        end
        
        function checkSource(app)
            
            for a=1:numel(app.S.Profile)
                app.S.Profile(a).Datasource=...
                    app.S.Source.Source(app.S.Profile(a).SourceId);
            end
            
        end
        
   
    end
    
    methods (Access = public)
        function updateAvailableData(app)
            app.Files=getSessionsToAnalyze(app.P);
            app.AvailableData.Items=app.Files.Y;
        end
        
        function newprofilecreated(app)
            startupFcn(app)
            SelectProfileDropDownValueChanged(app)
            %app.SelectProfileDropDown.Value=1;
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            loadSettings()
            app.S=S;
            app.SystemPath=pwd;
            app.baseF=baseF;
            app.Datum=datestr(now,'dd.mm.yyyy_HHMM');

            app.ProfileLabels=[string(app.SelectProfileDropDown.Items(1)) {app.S.Profile.Name}];
            app.SelectProfileDropDown.Items = app.ProfileLabels;
            
            
            % disable windows
            
            able(app,'off')

        end

        % Value changed function: SelectProfileDropDown
        function SelectProfileDropDownValueChanged(app, event)

            % Lamp red

            app.Lamp.Color='r';

            % update ProfileId
            value = app.SelectProfileDropDown.Value;
            app.ProfileId = find(strcmp(value,app.ProfileLabels),1)-1;

            if app.ProfileId==0 % check for 'none' and set state

                % don't show data

                app.ProfileTable.Data=[];
                app.ProfileTable.ColumnName={};
                app.AnalyzedData.Items={};
                app.AvailableData.Items = {};
                app.SessionReference.Items={};
                app.IndividualReferenceTable.Data=[];
                app.IndividualReferenceTable.ColumnName={};

                % disable windows
                
                able(app,'off')

            else
                
                % update Profile

                app.P=profileset(app.S,app.ProfileId);
                app.P=addDataBase(app.P);
                
                 % able windows
                
                able(app,'on')
                
                % update profile table

                rm={'Norm','Ref','Source'};
                NewProfileTable=rmfield(app.P,rm);
                NewProfileTable=struct2table(NewProfileTable);
                app.ProfileTable.Data=NewProfileTable;
                app.ProfileTable.ColumnName = NewProfileTable.Properties.VariableNames;
                app.P=addDataBase(app.P);
                
                % update analyzed data

                NewItems=getAnalyzedSessions(app.P.Rootfolder);
                app.AnalyzedData.Items=NewItems;

                % update data to analyze

                updateAvailableData(app)

                % update individual reference

%                 if isempty(app.P.Norm)
%                     app.IndividualReferenceTable.Data=[];
%                     app.IndividualReferenceTable.ColumnName={};
%                 else
                    updateIndReference(app)
%                 end

                % update Ref

                app = updateRefPanel (app);

            end
            
            % Lamp green
          
            app.Lamp.Color='g';
            
        end

        % Callback function
        function SelectDataButtonPushed(app, event)
            try
                app.DirNames=uigetdir2;
                app.AvailableData.Items=app.DirNames;
            catch
            end

        end

        % Button pushed function: CreateReportButton
        function CreateReportButtonPushed(app, event)
          
        end

        % Button pushed function: AnalyzeDataButton
        function AnalyzeDataButtonPushed(app, event)

            % Lamp green
          
            app.Lamp.Color='r';
            pause(1)
            
            % pick sessions
            
            files = pickDataToAnalyze(app);

            % analyze Sessions
            
            app.P=analyzeGames(app.S,app.P,files,app.Datum);
            
            % update panels
            
            NewItems=getAnalyzedSessions(app.P.Rootfolder);
            app.AnalyzedData.Items=NewItems;
            updateIndReference(app)
            
            % Lamp red
          
            app.Lamp.Color='g';

        end

        % Button pushed function: CreateReferenceButton
        function CreateReferenceButtonPushed(app, event)
            % Lamp green
          
            app.Lamp.Color='r';

            % get ref name
            
            prompt = {'Enter Reference Name'};
            dlgtitle = 'Create Reference';
            dims = [1 35];
            answer = inputdlg(prompt,dlgtitle,dims);
            name=char(answer);
            
            % pick sessions
            
            files=app.AnalyzedData.Value;
           
            % get tables
            
            T=getTablesforRef(files,app.P);
            
            % calc ref
            
            Output=RefCalc(T,app.S.VarNames,name);
            [app.S app.P]=appendRef(app.S,Output,app.ProfileId);
            
            % update panel
            
            RefList={app.P.Ref.Name};
            app.SessionReference.Items=RefList;
            
            % save ref
                      
            saveSettings(app)
            
            % Lamp red
          
            app.Lamp.Color='g';
            
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

% % Close request function 
% % to display a question dialog box 
%    selection = questdlg('Close This Figure?',...
%       'Close Request Function',...
%       'Yes','No','Yes'); 
%    switch selection 
%       case 'Yes'
%          delete(gcf)
%       case 'No'
%       return 
%    end
            delete(app)
            
        end

        % Button pushed function: DeleteReferenceButton
        function DeleteReferenceButtonPushed(app, event)

            % delete ref
            
            [~,id]=ismember(app.SessionReference.Value,{app.P.Ref.Name});
            
            selection = questdlg('Delete Reference?',...
                'Delete Reference',...
                'Yes','No','Yes');
            switch selection
                case 'Yes'
                    app.P.Ref(id)=[];
                case 'No'
                    return
            end

            % update ref panel
            
            app = updateRefPanel (app);
            
            % save Settings
            
            app.S.Profile(app.ProfileId).Ref=app.P.Ref;
            saveSettings(app)
            
        end

        % Button pushed function: DeleteProfileButton
        function DeleteProfileButtonPushed(app, event)
            
            % delete profile
            
            [~,id]=ismember(string(app.SelectProfileDropDown.Value),string({app.S.Profile.Name}));
            
            selection = questdlg('Delete Profile?',...
                'Delete Profile',...
                'Yes','No','Yes');
            switch selection
                case 'Yes'
                    app.S.Profile(id)=[];
                case 'No'
                    return
            end
            
            % save Settings
            
            saveSettings(app)
            
            startupFcn(app)
            
            SelectProfileDropDownValueChanged(app, event)         
                                             
        end

        % Button pushed function: SaveProfileButton
        function SaveProfileButtonPushed(app, event)
            
            selection = questdlg('Save Profile?',...
                'Save Profile',...
                'Yes','No','Yes');
            switch selection
                case 'Yes'
                    T=app.ProfileTable.Data;
                    SaveProfile=table2struct(T);
                    SaveProfile=rmfield(SaveProfile,'DB');
                    NewNorm=app.IndividualReferenceTable.Data;
                    SaveProfile.Norm=NewNorm;
                    SaveProfile.Ref=app.P.Ref;
               
                    app.S.Profile(app.ProfileId)=SaveProfile;
                    app.S.Profile(app.ProfileId)
                    saveSettings(app)
                case 'No'
                    return
            end

        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            
            % Lamp green
          
            app.Lamp.Color='r';
            
            % pick sessions
            
            files=app.AnalyzedData.Value;
                     
            exportTables(files, app.P)
            
            % Lamp red
          
            app.Lamp.Color='g';


        end

        % Button pushed function: RemoveAnalyzedButton
        function RemoveAnalyzedButtonPushed(app, event)
            
            % get items from panels
            
            An=string(app.AnalyzedData.Items);
            Av=string(app.AvailableData.Items);
            
            % transform to match yyyymmdd_HHMMSS

            An=extractBefore(An,".mat");
            id=strfind(Av,"_");
            id=cell2mat(id);
            Av=extractBefore(Av,id(:,2));            
            
            % delete analyzed items
            
            id=ismember(Av,An);
            app.AvailableData.Items(id)=[];
                   
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.Files=getSessionsToAnalyze(app.P);
            app.AvailableData.Items=app.Files.Y;
        end

        % Button pushed function: OnlineDataButton
        function OnlineDataButtonPushed(app, event)
            
            % import
            
            import matlab.net.*
            import matlab.net.http.*
            
            % Authorization
            
            cd(app.baseF)
          
            access_token=authorizePolar;
            
            [AllSessions, M]=getSessions(app.P.api,access_token);
            
            % start API app
            
            app.APIapp = APIapp(app, AllSessions, M, app.P);
            
        end

        % Button pushed function: NewProfileButton
        function NewProfileButtonPushed(app, event)
            app.ProfileApp = NewProfile(app);
        end

        % Button pushed function: ExportTableButton
        function ExportTableButtonPushed(app, event)
            cd(app.P.Rootfolder)
            ExportTable=app.IndividualReferenceTable.Data;
            writetable(ExportTable,'Norm_Export.xlsx')
        end

        % Button pushed function: ImportTableButton
        function ImportTableButtonPushed(app, event)
            [file,path] = uigetfile('*.xls*');
            if isequal(file,0)
                return
            else
                T=readtable(fullfile(path,file))
                app.IndividualReferenceTable.Data=T;
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ProfilePanel = uipanel(app.UIFigure);
            app.SelectProfileDropDownLabel = uilabel(app.ProfilePanel);
            app.SelectProfileDropDownLabel.Text = 'Select Profile';
            app.SelectProfileDropDown = uidropdown(app.ProfilePanel);
            app.SelectProfileDropDown.Items = {'None'};
            app.SelectProfileDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectProfileDropDownValueChanged, true);
            app.SelectProfileDropDown.Value = 'None';
            app.ProfileTable = uitable(app.ProfilePanel);
            app.ProfileTable.ColumnName = {''};
            app.ProfileTable.RowName = {};
            app.ProfileTable.ColumnEditable = true;
            app.NewProfileButton = uibutton(app.ProfilePanel, 'push');
            app.NewProfileButton.ButtonPushedFcn = createCallbackFcn(app, @NewProfileButtonPushed, true);
            app.NewProfileButton.Text = 'New Profile';
            app.DeleteProfileButton = uibutton(app.ProfilePanel, 'push');
            app.DeleteProfileButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteProfileButtonPushed, true);
            app.DeleteProfileButton.Text = 'Delete Profile';
            app.SaveProfileButton = uibutton(app.ProfilePanel, 'push');
            app.SaveProfileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveProfileButtonPushed, true);
            app.SaveProfileButton.Text = 'Save Profile';
            app.ReportPanel = uipanel(app.UIFigure);
            app.AnalyzedDataLabel = uilabel(app.ReportPanel);
            app.AnalyzedDataLabel.Text = 'Analyzed Data';
            app.AnalyzedData = uilistbox(app.ReportPanel);
            app.AnalyzedData.Items = {};
            app.AnalyzedData.Multiselect = 'on';
            app.AnalyzedData.Value = {};
            app.CreateReportButton = uibutton(app.ReportPanel, 'push');
            app.CreateReportButton.ButtonPushedFcn = createCallbackFcn(app, @CreateReportButtonPushed, true);
            app.CreateReportButton.Text = 'Create Report';
            app.ExportButton = uibutton(app.ReportPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.CreateReferenceButton = uibutton(app.ReportPanel, 'push');
            app.CreateReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @CreateReferenceButtonPushed, true);
            app.CreateReferenceButton.Text = 'Create Reference';
            app.Switch = uiswitch(app.ReportPanel, 'slider');
            app.Switch.Items = {'Training', 'Game'};
            app.Switch.Value = 'Training';
            app.AnalysisPanel = uipanel(app.UIFigure);
            app.AvailableDataLabel = uilabel(app.AnalysisPanel);
            app.AvailableDataLabel.Text = 'Available Data';
            app.AvailableData = uilistbox(app.AnalysisPanel);
            app.AvailableData.Items = {};
            app.AvailableData.Multiselect = 'on';
            app.AvailableData.Value = {};
            app.Lamp = uilamp(app.AnalysisPanel);
            app.AnalyzeDataButton = uibutton(app.AnalysisPanel, 'push');
            app.AnalyzeDataButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeDataButtonPushed, true);
            app.AnalyzeDataButton.Text = 'Analyze Data';
            app.RefreshButton = uibutton(app.AnalysisPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.RemoveAnalyzedButton = uibutton(app.AnalysisPanel, 'push');
            app.RemoveAnalyzedButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveAnalyzedButtonPushed, true);
            app.RemoveAnalyzedButton.Text = 'Remove Analyzed';
            app.OnlineDataButton = uibutton(app.AnalysisPanel, 'push');
            app.OnlineDataButton.ButtonPushedFcn = createCallbackFcn(app, @OnlineDataButtonPushed, true);
            app.OnlineDataButton.Text = 'Online Data';
            app.ReferencePanel = uipanel(app.UIFigure);
            app.SessionReferenceLabel = uilabel(app.ReferencePanel);
            app.SessionReferenceLabel.Text = 'Session Reference';
            app.SessionReference = uilistbox(app.ReferencePanel);
            app.SessionReference.Items = {};
            app.SessionReference.Multiselect = 'on';
            app.SessionReference.Value = {};
            app.IndividualReferenceTable = uitable(app.ReferencePanel);
            app.IndividualReferenceTable.ColumnName = {'LfdNr'; 'SpielerNr'; 'Name'; 'Vorname'; 'Alter'; 'Größe'; 'Gewicht'; 'MP4'; 'EC0'};
            app.IndividualReferenceTable.RowName = {};
            app.IndividualReferenceTable.ColumnSortable = true;
            app.IndividualReferenceTable.ColumnEditable = true;
            app.IndividualReferenceLabel = uilabel(app.ReferencePanel);
            app.IndividualReferenceLabel.Text = 'Individual Reference';
            app.DeleteReferenceButton = uibutton(app.ReferencePanel, 'push');
            app.DeleteReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteReferenceButtonPushed, true);
            app.DeleteReferenceButton.Text = 'Delete Reference';
            app.ExportTableButton = uibutton(app.ReferencePanel, 'push');
            app.ExportTableButton.ButtonPushedFcn = createCallbackFcn(app, @ExportTableButtonPushed, true);
            app.ExportTableButton.Text = 'Export Table';
            app.ImportTableButton = uibutton(app.ReferencePanel, 'push');
            app.ImportTableButton.ButtonPushedFcn = createCallbackFcn(app, @ImportTableButtonPushed, true);
            app.ImportTableButton.Text = 'Import Table';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MPA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end